classdef GPD_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Label            matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        DropDown_2Label  matlab.ui.control.Label
        DropDown_2       matlab.ui.control.DropDown
        DropDown_3Label  matlab.ui.control.Label
        DropDown_3       matlab.ui.control.DropDown
        DropDown_4Label  matlab.ui.control.Label
        DropDown_4       matlab.ui.control.DropDown
        DropDown_5Label  matlab.ui.control.Label
        DropDown_5       matlab.ui.control.DropDown
        Button           matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        gpd_table table;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.gpd_table=readtable("API_NY.GDP.MKTP.PP.CD_DS2_en_excel_v2_2445324.xls");
            app.DropDown.Items=["None"];
            app.DropDown_2.Items=["None"];
            app.DropDown_3.Items=["None"];
            app.DropDown_4.Items=["None"];
            app.DropDown_5.Items=["None"];
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            plot(app.UIAxes,1990:1:2021,zeros(1,length(1990:1:2021)),'w');
            hold(app.UIAxes,'on');
            if(app.DropDown.Value~="None")
                country=app.DropDown.Value;
                gpd=table2array(app.gpd_table(string(app.gpd_table.CountryName(:))==country,35:64));
                time=1990:1:2021;
                time=time(~isnan(gpd));
                gpd=gpd(~isnan(gpd));
                gpd_percent=zeros(1,length(gpd));
                for i=2:1:length(gpd)
                    gpd_percent(i)=((gpd(i)-gpd(i-1))/gpd(i-1))*100;
                end
                plot(app.UIAxes,time,gpd_percent,'r');
            end
            if(app.DropDown_2.Value~="None")
                country=app.DropDown_2.Value;
                gpd=table2array(app.gpd_table(string(app.gpd_table.CountryName(:))==country,35:64));
                time=1990:1:2021;
                time=time(~isnan(gpd));
                gpd=gpd(~isnan(gpd));
                gpd_percent=zeros(1,length(gpd));
                for i=2:1:length(gpd)
                    gpd_percent(i)=((gpd(i)-gpd(i-1))/gpd(i-1))*100;
                end
                plot(app.UIAxes,time,gpd_percent,'g');
            end
            if(app.DropDown_3.Value~="None")
                country=app.DropDown_3.Value;
                gpd=table2array(app.gpd_table(string(app.gpd_table.CountryName(:))==country,35:64));
                time=1990:1:2021;
                time=time(~isnan(gpd));
                gpd=gpd(~isnan(gpd));
                gpd_percent=zeros(1,length(gpd));
                for i=2:1:length(gpd)
                    gpd_percent(i)=((gpd(i)-gpd(i-1))/gpd(i-1))*100;
                end
                plot(app.UIAxes,time,gpd_percent,'b');
            end
            if(app.DropDown_4.Value~="None")
                country=app.DropDown_4.Value;
                gpd=table2array(app.gpd_table(string(app.gpd_table.CountryName(:))==country,35:64));
                time=1990:1:2021;
                time=time(~isnan(gpd));
                gpd=gpd(~isnan(gpd));
                gpd_percent=zeros(1,length(gpd));
                for i=2:1:length(gpd)
                    gpd_percent(i)=((gpd(i)-gpd(i-1))/gpd(i-1))*100;
                end
                plot(app.UIAxes,time,gpd_percent,'c');
            end
            if(app.DropDown_5.Value~="None")
                country=app.DropDown_5.Value;
                gpd=table2array(app.gpd_table(string(app.gpd_table.CountryName(:))==country,35:64));
                time=1990:1:2021;
                time=time(~isnan(gpd));
                gpd=gpd(~isnan(gpd));
                gpd_percent=zeros(1,length(gpd));
                for i=2:1:length(gpd)
                    gpd_percent(i)=((gpd(i)-gpd(i-1))/gpd(i-1))*100;
                end
                plot(app.UIAxes,time,gpd_percent,'m');
            end
            
            hold(app.UIAxes,'off');
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.gpd_table=readtable(uigetfile('*.xls'));
            app.DropDown.Items=['None';app.gpd_table.CountryName];
            app.DropDown_2.Items=['None';app.gpd_table.CountryName];
            app.DropDown_3.Items=['None';app.gpd_table.CountryName];
            app.DropDown_4.Items=['None';app.gpd_table.CountryName];
            app.DropDown_5.Items=['None';app.gpd_table.CountryName];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Страна 1';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = 'Страна 2';
            app.DropDown_2 = uidropdown(app.UIFigure);
            app.DropDown_3Label = uilabel(app.UIFigure);
            app.DropDown_3Label.Text = 'Страна 3';
            app.DropDown_3 = uidropdown(app.UIFigure);
            app.DropDown_4Label = uilabel(app.UIFigure);
            app.DropDown_4Label.Text = 'Страна 4';
            app.DropDown_4 = uidropdown(app.UIFigure);
            app.DropDown_5Label = uilabel(app.UIFigure);
            app.DropDown_5Label.Text = 'Страна 5';
            app.DropDown_5 = uidropdown(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить график';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = {'Выбрать файл'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Годовой процентный прирост ВВП')
            xlabel(app.UIAxes, 'Год')
            ylabel(app.UIAxes, '%')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GPD_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end