classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Label_4           matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        EditField_5Label  matlab.ui.control.Label
        EditField_5       matlab.ui.control.EditField
        EditField_6Label  matlab.ui.control.Label
        EditField_6       matlab.ui.control.EditField
        EditField_7Label  matlab.ui.control.Label
        EditField_7       matlab.ui.control.EditField
        EditField_8Label  matlab.ui.control.Label
        EditField_8       matlab.ui.control.EditField
        EditField_9Label  matlab.ui.control.Label
        EditField_9       matlab.ui.control.EditField
        EditField_2Label  matlab.ui.control.Label
        EditField_2       matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        EditField_3       matlab.ui.control.NumericEditField
        EditField_4Label  matlab.ui.control.Label
        EditField_4       matlab.ui.control.NumericEditField
        Label             matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        Label_3           matlab.ui.control.Label
        Button            matlab.ui.control.Button
        Label_7           matlab.ui.control.Label
        EditField_10      matlab.ui.control.NumericEditField
        Label_5           matlab.ui.control.Label
        Label_6           matlab.ui.control.Label
        Label_8           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Label_6.Text=int2str(app.EditField_2.Value+app.EditField_3.Value+app.EditField_4.Value);
            if(app.EditField_2.Value+app.EditField_3.Value+app.EditField_4.Value>=app.EditField_10.Value)
                app.Label_8.Text="Поздравляем!!!";
            else
                app.Label_8.Text="Соболезнуем...";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'Фамилия';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField_5Label = uilabel(app.UIFigure);
            app.EditField_5Label.Text = 'Имя';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_6Label = uilabel(app.UIFigure);
            app.EditField_6Label.Text = 'Отчество';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            app.EditField_7Label = uilabel(app.UIFigure);
            app.EditField_7Label.Text = 'Предмет1';
            app.EditField_7 = uieditfield(app.UIFigure, 'text');
            app.EditField_8Label = uilabel(app.UIFigure);
            app.EditField_8Label.Text = 'Предмет 2';
            app.EditField_8 = uieditfield(app.UIFigure, 'text');
            app.EditField_9Label = uilabel(app.UIFigure);
            app.EditField_9Label.Text = 'Предмет3';
            app.EditField_9 = uieditfield(app.UIFigure, 'text');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4Label = uilabel(app.UIFigure);
            app.EditField_4Label.Text = '';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ФИО';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Предметы';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Баллы';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Рассчитать';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = 'Проходные баллы';
            app.EditField_10 = uieditfield(app.UIFigure, 'numeric');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'Сумма';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end