classdef GuiTask2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        CheckBox    matlab.ui.control.CheckBox
        CheckBox_2  matlab.ui.control.CheckBox
        Button      matlab.ui.control.Button
        UIAxes      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            x=linspace(0,4*pi,360);
            y=sin(x);
            y1=cos(x);
            plot(app.UIAxes,0,0);
            
            if(app.CheckBox.Value)
                plot(app.UIAxes,x,y);
                if(app.CheckBox_2.Value)
                    plot(app.UIAxes,x,[y;y1]);
                end
            else
                if(app.CheckBox_2.Value)
                    plot(app.UIAxes,x,y1);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = 'График синуса';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = 'График косинуса';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Графики')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiTask2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end